classdef Bead_Tracker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TLevelsLabel                  matlab.ui.control.Label
        Edit_TLevels                  matlab.ui.control.NumericEditField
        OuterCEditFieldLabel          matlab.ui.control.Label
        Edit_OuterC                   matlab.ui.control.NumericEditField
        ExperimentnameEditFieldLabel  matlab.ui.control.Label
        Edit_ExperimentName           matlab.ui.control.EditField
        RangeLabel                    matlab.ui.control.Label
        Edit_Range                    matlab.ui.control.EditField
        START                         matlab.ui.control.Button
        STOP                          matlab.ui.control.Button
        DisplayBeadFit_Box            matlab.ui.control.CheckBox
        DisplayMasks_Box              matlab.ui.control.CheckBox
        showinnerfit_Box              matlab.ui.control.CheckBox
        showouterfit_Box              matlab.ui.control.CheckBox
        showsearcharea_Box            matlab.ui.control.CheckBox
        Method2_Box                   matlab.ui.control.CheckBox
        InnerCEditFieldLabel          matlab.ui.control.Label
        Edit_InnerC                   matlab.ui.control.NumericEditField
        TextOut                       matlab.ui.control.TextArea
        Load_Stack                    matlab.ui.control.Button
        StackEditFieldLabel           matlab.ui.control.Label
        Edit_Stack                    matlab.ui.control.EditField
        EndPlot_Box                   matlab.ui.control.CheckBox
        Switch                        matlab.ui.control.Switch
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global OPT
            
            % Buttons Options
            OPT.START = 0 ;   
            OPT.STOP = 1 ;
            OPT.ERROR = 0 ;
            
            OPT.T_levels = app.Edit_TLevels.Value; 
            OPT.f1 = app.Edit_InnerC.Value;   
            OPT.f2 = app.Edit_OuterC.Value; 
            OPT.Exp_name = app.Edit_ExperimentName.Value;       
            OPT.Range = app.Edit_Range.Value;  
            OPT.PreviewPlot = app.EndPlot_Box.Value;  
            
            OPT.Disp_Bead = app.DisplayBeadFit_Box.Value;
            OPT.Disp_Masks = app.DisplayMasks_Box.Value;
            OPT.Disp_Dark_C = app.showinnerfit_Box.Value;
            OPT.Disp_Light_C = app.showouterfit_Box.Value;
            OPT.Disp_Search_C = app.showsearcharea_Box.Value;
            
            OPT.Method_2 = app.Method2_Box.Value;
            
        end

        % Value changed function: Edit_TLevels
        function Edit_TLevelsValue_Changed(app, event)
            global OPT;
            OPT.T_levels = app.Edit_TLevels.Value;            
        end

        % Value changed function: Edit_OuterC
        function Edit_OuterCValue_Changed(app, event)
            global OPT;
            OPT.f2 = app.Edit_OuterC.Value;            
        end

        % Value changed function: Edit_InnerC
        function Edit_InnerCValue_Changed(app, event)
            global OPT
            OPT.f1 = app.Edit_InnerC.Value;            
        end

        % Value changed function: Edit_ExperimentName
        function Edit_ExperimentNameValue_Changed(app, event)
            global OPT
            OPT.Exp_name = app.Edit_ExperimentName.Value;            
        end

        % Value changed function: Edit_Range
        function Edit_RangeValue_Changed(app, event)
            global OPT;
            OPT.Range = app.Edit_Range.Value;            
        end

        % Value changed function: DisplayBeadFit_Box
        function DisplayBeadFit_BoxValue_Changed(app, event)
            global OPT;
            OPT.Disp_Bead = app.DisplayBeadFit_Box.Value;
          
        end

        % Value changed function: DisplayMasks_Box
        function DisplayMasks_BoxValue_Changed(app, event)
            global OPT;
            OPT.Disp_Masks = app.DisplayMasks_Box.Value;
            
        end

        % Value changed function: showinnerfit_Box
        function showinnerfit_BoxValue_Changed(app, event)
            global OPT;
            OPT.Disp_Dark_C = app.showinnerfit_Box.Value;
            
        end

        % Value changed function: showouterfit_Box
        function showouterfit_BoxValue_Changed(app, event)
            global OPT;
            OPT.Disp_Light_C = app.showouterfit_Box.Value;
            
        end

        % Value changed function: showsearcharea_Box
        function showsearcharea_BoxValue_Changed(app, event)
            global OPT;
            OPT.Disp_Search_C = app.showsearcharea_Box.Value;
            
        end

        % Value changed function: Method2_Box
        function Method2_BoxValue_Changed(app, event)
            global OPT;
            OPT.Method_2 = app.Method2_Box.Value;
            
        end

        % Button pushed function: START
        function STARTButton(app, event)
            %% START 1 _____________________________________________________________________________________________________________________________
            % --------------------------------------------------------------------------------------------------------------
            global OPT ;
            OPT.START = 1;
            OPT.STOP = 0 ;
            % Disable Start & Stop
            app.START.Enable = 'off';
            app.TextOut.BackgroundColor = [0.75 0.3 0.3] ;           % Red bkgr
            
            if strcmp(app.Switch.Value ,'BeadTrack')
                MAIN_BeadTracker(app);
            elseif strcmp(app.Switch.Value , 'Rotation')
                MAIN_Rotation(app);
            end            
            
            if OPT.ERROR == 0
                if OPT.STOP == 0 
                    app.TextOut.Value = sprintf('%s', '... Idle ...' );
                    app.TextOut.BackgroundColor = [0.65 1 0.4] ;     % green bkgr
                elseif OPT.STOP == 1
                    app.TextOut.Value = sprintf('%s', 'Process aborted !!!'); 
                    app.TextOut.BackgroundColor = [0.9 0.6 0.3] ;    % Orange bkgr
                end
            elseif OPT.ERROR > 0
                app.TextOut.BackgroundColor = [0.9 0.6 0.3] ;        % Orange bkgr
                OPT.ERROR = 0;
            end
              
            OPT.START_1 = 0;
            OPT.STOP = 1;
            % Once finished, reenable buttons
            app.START.Enable = 'on';
        end

        % Button pushed function: STOP
        function STOPButton(app, event)
            %% STOP 1 _____________________________________________________________________________________________________________________________
            % --------------------------------------------------------------------------------------------------------------
            global OPT ;
            OPT.STOP = 1 ;
            OPT.ERROR = 0 ;
            app.TextOut.BackgroundColor = [0.65 0.65 0.65] ;    % grey color
            app.START.Enable = 'on';
            clearvars -except OPT app;
        end

        % Button pushed function: Load_Stack
        function Load_Stack_Button(app, event)
            global OPT ;
            [PathName, FoldName]  = fileparts(uigetdir) ;
            OPT.path = PathName ;
            OPT.fold = FoldName ;            
            app.Edit_Stack.Value = [PathName, FoldName];
        end

        % Value changed function: EndPlot_Box
        function EndPlot_BoxValue_Changed(app, event)
            global OPT;
            OPT.PreviewPlot = app.EndPlot_Box.Value; 
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)

            if strcmp(app.Switch.Value ,'BeadTrack')
                app.DisplayBeadFit_Box.Enable = 'on';
                app.DisplayMasks_Box.Enable = 'on';
                app.showinnerfit_Box.Enable = 'on';
                app.showouterfit_Box.Enable = 'on';
                app.showsearcharea_Box.Enable = 'on';
                app.Method2_Box.Enable = 'on';
                app.Edit_InnerC.Enable = 'on';
                app.EndPlot_Box.Enable = 'on';
                app.Edit_OuterC.Enable = 'on';
                app.Edit_TLevels.Enable = 'on';                
            elseif strcmp(app.Switch.Value , 'Rotation')
                app.DisplayBeadFit_Box.Enable = 'off';
                app.DisplayMasks_Box.Enable = 'off';
                app.showinnerfit_Box.Enable = 'off';
                app.showouterfit_Box.Enable = 'off';
                app.showsearcharea_Box.Enable = 'off';
                app.Method2_Box.Enable = 'off';
                app.Edit_InnerC.Enable = 'off';
                app.EndPlot_Box.Enable = 'off';
                app.Edit_OuterC.Enable = 'off';  
                app.Edit_TLevels.Enable = 'off'; 
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TLevelsLabel = uilabel(app.UIFigure);
            app.TLevelsLabel.Text = 'T. Levels';
            app.Edit_TLevels = uieditfield(app.UIFigure, 'numeric');
            app.Edit_TLevels.ValueChangedFcn = createCallbackFcn(app, @Edit_TLevelsValue_Changed, true);
            app.Edit_TLevels.Limits = [1 20];
            app.Edit_TLevels.Value = 2;
            app.OuterCEditFieldLabel = uilabel(app.UIFigure);
            app.OuterCEditFieldLabel.Text = 'Outer C';
            app.Edit_OuterC = uieditfield(app.UIFigure, 'numeric');
            app.Edit_OuterC.ValueChangedFcn = createCallbackFcn(app, @Edit_OuterCValue_Changed, true);
            app.Edit_OuterC.Limits = [0 3];
            app.Edit_OuterC.ValueDisplayFormat = '%.2f';
            app.Edit_OuterC.Value = 1.3;
            app.ExperimentnameEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentnameEditFieldLabel.Text = 'Experiment name';
            app.Edit_ExperimentName = uieditfield(app.UIFigure, 'text');
            app.Edit_ExperimentName.ValueChangedFcn = createCallbackFcn(app, @Edit_ExperimentNameValue_Changed, true);
            app.RangeLabel = uilabel(app.UIFigure);
            app.RangeLabel.Text = 'Range:';
            app.Edit_Range = uieditfield(app.UIFigure, 'text');
            app.Edit_Range.ValueChangedFcn = createCallbackFcn(app, @Edit_RangeValue_Changed, true);
            app.Edit_Range.Value = 'S:E';
            app.START = uibutton(app.UIFigure, 'push');
            app.START.ButtonPushedFcn = createCallbackFcn(app, @STARTButton, true);
            app.START.Text = 'START';
            app.STOP = uibutton(app.UIFigure, 'push');
            app.STOP.ButtonPushedFcn = createCallbackFcn(app, @STOPButton, true);
            app.STOP.Text = 'STOP';
            app.DisplayBeadFit_Box = uicheckbox(app.UIFigure);
            app.DisplayBeadFit_Box.ValueChangedFcn = createCallbackFcn(app, @DisplayBeadFit_BoxValue_Changed, true);
            app.DisplayBeadFit_Box.Text = 'Display Bead Fitting';
            app.DisplayBeadFit_Box.Value = true;
            app.DisplayMasks_Box = uicheckbox(app.UIFigure);
            app.DisplayMasks_Box.ValueChangedFcn = createCallbackFcn(app, @DisplayMasks_BoxValue_Changed, true);
            app.DisplayMasks_Box.Text = 'Display Masks';
            app.showinnerfit_Box = uicheckbox(app.UIFigure);
            app.showinnerfit_Box.ValueChangedFcn = createCallbackFcn(app, @showinnerfit_BoxValue_Changed, true);
            app.showinnerfit_Box.Text = 'show inner fitting';
            app.showinnerfit_Box.Value = true;
            app.showouterfit_Box = uicheckbox(app.UIFigure);
            app.showouterfit_Box.ValueChangedFcn = createCallbackFcn(app, @showouterfit_BoxValue_Changed, true);
            app.showouterfit_Box.Text = 'show outer fitting';
            app.showsearcharea_Box = uicheckbox(app.UIFigure);
            app.showsearcharea_Box.ValueChangedFcn = createCallbackFcn(app, @showsearcharea_BoxValue_Changed, true);
            app.showsearcharea_Box.Text = 'show search area';
            app.Method2_Box = uicheckbox(app.UIFigure);
            app.Method2_Box.ValueChangedFcn = createCallbackFcn(app, @Method2_BoxValue_Changed, true);
            app.Method2_Box.Text = 'use Method 2';
            app.InnerCEditFieldLabel = uilabel(app.UIFigure);
            app.InnerCEditFieldLabel.Text = 'Inner C';
            app.Edit_InnerC = uieditfield(app.UIFigure, 'numeric');
            app.Edit_InnerC.ValueChangedFcn = createCallbackFcn(app, @Edit_InnerCValue_Changed, true);
            app.Edit_InnerC.Limits = [0 3];
            app.Edit_InnerC.ValueDisplayFormat = '%.2f';
            app.Edit_InnerC.Value = 0.8;
            app.TextOut = uitextarea(app.UIFigure);
            app.TextOut.Value = {'... IDLE ...'};
            app.Load_Stack = uibutton(app.UIFigure, 'push');
            app.Load_Stack.ButtonPushedFcn = createCallbackFcn(app, @Load_Stack_Button, true);
            app.Load_Stack.Text = 'Load ...';
            app.StackEditFieldLabel = uilabel(app.UIFigure);
            app.StackEditFieldLabel.Text = 'Stack';
            app.Edit_Stack = uieditfield(app.UIFigure, 'text');
            app.Edit_Stack.Editable = 'off';
            app.EndPlot_Box = uicheckbox(app.UIFigure);
            app.EndPlot_Box.ValueChangedFcn = createCallbackFcn(app, @EndPlot_BoxValue_Changed, true);
            app.EndPlot_Box.Text = 'Preview End_Plot';
            app.EndPlot_Box.Value = true;
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'BeadTrack', 'Rotation'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'BeadTrack';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Bead_Tracker()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end